classdef EIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EITUIFigure              matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Imaging                  matlab.ui.container.Tab
        eidorsReadyLamp          matlab.ui.control.Lamp
        loadEidorsButton         matlab.ui.control.Button
        imageConstructionButton  matlab.ui.control.Button
        sampleOneTimeButton      matlab.ui.control.Button
        logLabel                 matlab.ui.control.Label
        statusLamp               matlab.ui.control.Lamp
        readyLamp                matlab.ui.control.Lamp
        serialComDropDown        matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        Lamp                     matlab.ui.control.Lamp
        openSerialButton         matlab.ui.control.Button
        getHomogeneousButton     matlab.ui.control.Button
        UIAxesV0                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        DeviceName = 'STMicroelectronics STLink Virtual COM Port';
        CurentCmd = 0 %当前发送的请求数据类型 0无效 1采集1次数据 2均匀介质数据
        serialObj;
    end

    methods (Access = public)
        function RxSerialDataCallback(app,src, ~)
            n = src.NumBytesAvailable
            %disp(n)
            if n==256*4
                raw_data = read(src,256,"uint32")
                %disp(num2str(raw_data))
                if app.CurentCmd==2
                    plot(app.UIAxesV0,app.ff(raw_data),'or-');
                    app.CurentCmd=0
                    app.readyLamp.Color='green'
                    app.TextArea.Value='保存均匀场数据';
                    fd=fopen('D:\UofT\Capstone\eit_gui\output3\vvRefRaw.txt','w+t');%保存均匀介质帧原始电压数据到文件
                    fprintf(fd,'%g\r\n',raw_data);
                    fclose(fd);
                    fd=fopen(['D:\UofT\Capstone\eit_gui\output3\' 'EitData_vvRefRaw_' datestr(now,'yyyy-mm-dd_HH_MM_SS') '.txt'],'w+t');
                    fprintf(fd,'%g\r\n',raw_data);
                    fclose(fd);
                end
                if app.CurentCmd==1
                    plot(app.UIAxes,app.ff(raw_data),'ob-');
                    app.CurentCmd=0
                    app.statusLamp.Color='green'
                    app.TextArea.Value='保存采集数据';
                    fid=fopen('D:\UofT\Capstone\eit_gui\output3\vvAvgRaw.txt','wt');%保存本次测采集数据到文件
        			fprintf(fid,'%g\n',raw_data);
        			fclose(fid);
        			fd=fopen(['D:\UofT\Capstone\eit_gui\output3\' 'EitData_vvAvgRaw_' datestr(now,'yyyy-mm-dd_HH_MM_SS') '.txt'],'w+t');
        			fprintf(fd,'%g\r\n',raw_data);
        			fclose(fd);
                end
            end
        end
    end
    
    methods (Access = private)

        function results = ff(app,raw_data)

            %将原始数据组合成256个数据，小数形式存放，单位为V，例如0.123456V，小数部分6位有效
            img_voltage=zeros(256,1);%列向量，256行1列
            img_voltage=raw_data;

            img_voltage=double(img_voltage);%转换成浮点，8字节
            img_voltage=img_voltage./1000000.0;%单位由uV转换为V

            %单端输入信号时，需要把电压转化成两个电极之间的差值
            v=img_voltage;
            w=zeros(256,1);
            for i=1:256
            	if rem(i,16) == 0
            		w(i)=abs(v(i)-v(i-15));
            	else
            		w(i)=abs(v(i)-v(i+1));
            	end
            end
            img_voltage=w;
            results = img_voltage
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc;
            
            try
                %查找所有串口设备，存在二维向量中，第一列为设备名，第二列为端口号
                device = IdentifySerialComs;

                %disp(device)
                for i=1:length(device)
                    comnum=device{i,2}
                    com=['COM',num2str(comnum)];
                     %disp(com)
                     s = serialport(com,115200,ByteOrder="little-endian",FlowControl="software",Timeout=1)
                     configureTerminator(s,"CR/LF")
                      writeline(s,"which eit?")
                      res = readline(s)
                    
                      if res=="simple eit"
                          clear s
                          break
                      end
                      clear s
                      
                end

                disp(comnum)

                %将端口号字符串转换为元数组，并添加到下拉框
                app.serialComDropDown.Items = cellstr(com);
            catch
                
            end                        
            
        end

        % Button pushed function: getHomogeneousButton
        function getHomogeneousButtonPushed(app, event)
            app.CurentCmd=2;
            app.readyLamp.Color='red'
            write(app.serialObj,"sample",'string')          
            app.TextArea.Value='Getting Uniform Data...';
            
        end

        % Button pushed function: openSerialButton
        function openSerialButtonPushed(app, event)
            
            if app.openSerialButton.Text == 'OpenSerial'
                ports = strsplit(app.serialComDropDown.Value,' '); %获取当前下拉框选定的串口号
                port=ports{1,1};
                disp("choose port:"+port);
                
                 app.serialObj=serialport(port,115200,ByteOrder="little-endian",FlowControl="software")
                %configureTerminator(app.serialObj,"CR/LF")
                %disp(app.serialObj);

                flush(app.serialObj);
                %注册串口接收回调函数
                configureCallback(app.serialObj,"byte",256*4,@app.RxSerialDataCallback)

                app.Lamp.Color='green';
                app.openSerialButton.Text = 'CloseSerial';
                app.logLabel.Text = 'connected';
                
            elseif app.openSerialButton.Text == 'CloseSerial'

                delete(app.serialObj)

                app.Lamp.Color='red';
                app.openSerialButton.Text = 'OpenSerial';
                app.logLabel.Text = 'disconnected';
                
            end
            
        end

        % Close request function: EITUIFigure
        function EITUIFigureCloseRequest(app, event)

            delete(app);
            
        end

        % Button pushed function: sampleOneTimeButton
        function sampleOneTimeButtonPushed(app, event)
            app.CurentCmd=1
            app.statusLamp.Color='red'
            write(app.serialObj,"sample",'string')    
            app.TextArea.Value='Request Once';
        end

        % Button pushed function: imageConstructionButton
        function imageConstructionButtonPushed(app, event)
            phantom_test
        end

        % Button pushed function: loadEidorsButton
        function loadEidorsButtonPushed(app, event)
            run D:\UofT\Capstone\eit_gui\eidors-v3.11\eidors\startup.m
            app.eidorsReadyLamp.Color='green'
        end

        % Callback function
        function ButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EITUIFigure = uifigure('Visible', 'off');
            app.EITUIFigure.Name = 'EIT';
            app.EITUIFigure.CloseRequestFcn = createCallbackFcn(app, @EITUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.EITUIFigure);
            app.Imaging = uitab(app.TabGroup);
            app.Imaging.Title = 'Imaging';
            app.UIAxes = uiaxes(app.Imaging);
            title(app.UIAxes, 'Acquired Data')
            ylabel(app.UIAxes, 'Voltage/V')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'parula')
            app.UIAxesV0 = uiaxes(app.Imaging);
            title(app.UIAxesV0, 'Uniform Field')
            ylabel(app.UIAxesV0, 'Voltage/V')
            app.UIAxesV0.XTick = [];
            app.UIAxesV0.YTick = [];
            app.UIAxesV0.ZTick = [];
            colormap(app.UIAxesV0, 'parula')
            app.getHomogeneousButton = uibutton(app.Imaging, 'push');
            app.getHomogeneousButton.ButtonPushedFcn = createCallbackFcn(app, @getHomogeneousButtonPushed, true);
            app.getHomogeneousButton.Text = 'Acquire Uniform';
            app.openSerialButton = uibutton(app.Imaging, 'push');
            app.openSerialButton.ButtonPushedFcn = createCallbackFcn(app, @openSerialButtonPushed, true);
            app.openSerialButton.Text = 'OpenSerial';
            app.Lamp = uilamp(app.Imaging);
            app.TextArea = uitextarea(app.Imaging);
            app.Label = uilabel(app.Imaging);
            app.Label.Text = 'Port Number';
            app.serialComDropDown = uidropdown(app.Imaging);
            app.serialComDropDown.Items = {'COM25'};
            app.serialComDropDown.Value = 'COM25';
            app.readyLamp = uilamp(app.Imaging);
            app.statusLamp = uilamp(app.Imaging);
            app.logLabel = uilabel(app.Imaging);
            app.logLabel.Text = 'EIT system';
            app.sampleOneTimeButton = uibutton(app.Imaging, 'push');
            app.sampleOneTimeButton.ButtonPushedFcn = createCallbackFcn(app, @sampleOneTimeButtonPushed, true);
            app.sampleOneTimeButton.Text = 'Acquire Once';
            app.imageConstructionButton = uibutton(app.Imaging, 'push');
            app.imageConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @imageConstructionButtonPushed, true);
            app.imageConstructionButton.Text = 'Image';
            app.loadEidorsButton = uibutton(app.Imaging, 'push');
            app.loadEidorsButton.ButtonPushedFcn = createCallbackFcn(app, @loadEidorsButtonPushed, true);
            app.loadEidorsButton.Text = 'LOAD EIDORS';
            app.eidorsReadyLamp = uilamp(app.Imaging);
            % Show the figure after all components are created
            app.EITUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EIT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EITUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EITUIFigure)
        end
    end
end